classdef QuailKit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QuailkitUIFigure                matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ImportMenu                      matlab.ui.container.Menu
        LocalizeMenu                    matlab.ui.container.Menu
        SettingsMenu                    matlab.ui.container.Menu
        GridLayout2                     matlab.ui.container.GridLayout
        SpectrogramsPanel               matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        Button                          matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        Panel                           matlab.ui.container.Panel
        ModeSwitchLabel                 matlab.ui.control.Label
        ModeSwitch                      matlab.ui.control.Switch
        MapSwitchLabel                  matlab.ui.control.Label
        MapSwitch                       matlab.ui.control.Switch
        Panel_2                         matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        SiftButton                      matlab.ui.control.StateButton
        Button_5                        matlab.ui.control.StateButton
        Button_6                        matlab.ui.control.StateButton
        EditField                       matlab.ui.control.EditField
        OffButton                       matlab.ui.control.StateButton
        QuailCallDetectionLabel         matlab.ui.control.Label
        Previous10sLabel                matlab.ui.control.Label
        Next10sLabel                    matlab.ui.control.Label
        ManualAnnotationsLabel          matlab.ui.control.Label
        OffButton_2                     matlab.ui.control.StateButton
        UITable                         matlab.ui.control.Table
        NoRecordingsLoadedYetLamp       matlab.ui.control.Lamp
        NorecordingsloadedyetLabel      matlab.ui.control.Label
        QuailCallTemplateDropDownLabel  matlab.ui.control.Label
        QuailCallTemplateDropDown       matlab.ui.control.DropDown
        FinishButton                    matlab.ui.control.Button
        BatchprocessLabel               matlab.ui.control.Label
        MatchedCallsLabel               matlab.ui.control.Label
        FindButton                      matlab.ui.control.StateButton
        DistanceLabel                   matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        BatchProcessingTypeSwitchLabel  matlab.ui.control.Label
        BatchProcessingTypeSwitch       matlab.ui.control.Switch
        StartButton                     matlab.ui.control.Button
        DisplayModeDropDownLabel        matlab.ui.control.Label
        DisplayModeDropDown             matlab.ui.control.DropDown
        ChannelDropDownLabel            matlab.ui.control.Label
        ChannelDropDown                 matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        %General Properties
        dataLoaded = false% Spectrograms have been loaded.
        dataPath    %File directory containing the data.
        displayMode = 0 % spectrogram mode = 0 and scalogram mode = 1 %For Farshad 
        
        micPaths = []
        metPaths = []
        micNames = ["","","",""]  %microphone names
        
        %Audio Data
        AudioFilePlay
        AudioChannel
        %AudioSignal
        TS
        Fs %Sampling Frequency
        %F = 500:10:4000;
        F = 1000:10:3200;
        window_size = 0.04;
        noverlap_size = 0.8;     %round(0.8*wind);
        
        %Recordings: Audioplayer Object
        Recordings = cell(1,4);
        
        %Spectrograms
        Spectrograms = cell(1,4);
        S_Channels = cell(2,4);
        
        %Online Properties
        loadIntervalRate = 300 %Loads 5 minutes from disk
        loadSubIntervalRate = 10 %Brings in 10 seconds at a time
        
        loadInterval    %includes lower and upper bound of 5 minute interval of audio samples  : [lb ub]
        subInterval     %includes lower and upper bound of 10 second interval of audio samples : [lb ub]
        %%
        curLoadInterval %if this value is 1 it's in the first 5 minute interval
        curSubInterval %if this value is 1 it's in the first 10 second interval
        
        Samples %Amount of samples in the entire recording
        
        micpos = []
        localizations = []
        matchedMatrix = []
        
        template %Template for Quail Call detection
        
        Date %Date of recording
        
        CallsDetected = cell(1,4) %got from QCallDetection
        AnnotatedPos = cell(1,4) %got from annotationsBBox
        AnnotatedCalls = cell(1,4) %got from annotationsBBox
        
        speakerLoc
        
        %parallelRate = 2.872745098e7
        parallelRate = 2.441833333e7
        
        channel = "Both"
       
    end
    
    methods (Access = public)
        function time = convertToRealTime(app,time)
            maxDegree = floor(mean(log(time)/log(60)));
            maxDegree(maxDegree > 2) = 2;
            maxDegree(maxDegree < 0) = 0;
            temptime = zeros(length(time),3);
            for i = maxDegree:-1:0
                temptime(:,2 - i + 1) = floor(time/60^i);
                time = mod(time,60^i);
            end
            refTime = split(string(app.Date),' ');
            refTime = str2num(char(split(refTime(2),':')));
            temptime(:,1) = temptime(:,1) + refTime(1) + floor((temptime(:,2) + refTime(2))/60);
            temptime(:,2) = mod(temptime(:,2) + refTime(2)+floor((temptime(:,3) + refTime(3))/60),60);
            temptime(:,3) = mod(temptime(:,3) + refTime(3),60);
            temptime = string(temptime);
            temptime = temptime(:,1) + ":" + temptime(:,2) + ":" + temptime(:,3);
            time = temptime;
        end
        
        function app = UpdateAudio(app,c,varargin)
            updateLoadInterval = false;
            if ~isempty(varargin) && varargin{1,1}
                updateLoadInterval = true;
            end
            
            if c < app.Samples/app.Fs && c >= 0
                %Hold temporarily the current load interval
                j0 = app.curLoadInterval;
                
                %Find the new intervals based on the given time into the
                %recording in seconds
                app.curLoadInterval = floor(c/app.loadIntervalRate);
                app.curSubInterval = floor(mod(c,app.loadIntervalRate)/app.loadSubIntervalRate);
                
                %Set new subinterval
                if (app.curSubInterval+1)*app.loadSubIntervalRate + app.curLoadInterval*app.loadIntervalRate <= app.Samples/app.Fs
                    app.subInterval = [(app.curSubInterval==0)+app.curSubInterval*(app.loadSubIntervalRate*app.Fs),(app.curSubInterval+1)*app.loadSubIntervalRate*app.Fs];
                else
                    app.subInterval = [(app.curSubInterval==0)+app.curSubInterval*(app.loadSubIntervalRate*app.Fs),app.Fs*app.loadIntervalRate]; %
                end
                
                %If the load interval has changed, update the load interval
                %and load in a new interval of recording samples
                if abs(app.curLoadInterval - j0) > 0 || updateLoadInterval
                    if (app.curLoadInterval+1)*app.loadIntervalRate*app.Fs <= app.Samples
                       app.loadInterval  = [(app.curLoadInterval==0)+app.curLoadInterval*(app.loadIntervalRate*app.Fs),(app.curLoadInterval+1)*app.loadIntervalRate*app.Fs];
                    else
                       app.loadInterval  = [(app.curLoadInterval==0)+app.curLoadInterval*(app.loadIntervalRate*app.Fs),app.Samples];
                    end
                    app = HTdataAccess(app,'read');
                end
                
                %For Farshad
                %if app.displayMode
                [app.Spectrograms, app.S_Channels] = FBspectrogram(app);
                %else
                %scalogram function
                %end
                
                if app.OffButton.Value == 1
                    app.detectMapCalls();
                end
                
                if app.OffButton_2.Value == 1
                    [app.AnnotatedCalls,app.AnnotatedPos] = annotationsBBox(app);
                end
                
                if app.FindButton.Value == 1
                    [CallA,CallB,CallC,CallD,app.CallsDetected] = QCallDetection(app);
                    if (~isempty(CallA) + ~isempty(CallB) + ~isempty(CallC) + ~isempty(CallD))/4  >= 3/4
                        app.matchedMatrix = GM_MatchCalls(CallA,CallB,CallC,CallD,GM_EstimateMaxTimeLag(readtable(app.metPaths(1)),...
                            readtable(app.metPaths(2)),readtable(app.metPaths(3)),readtable(app.metPaths(4))));
                        LinesMatchedCalls(app);
                    end
                end
                
                if app.OffButton.Value == 1 && app.OffButton_2.Value == 1
                    [TP,FP,FN] = confusionMat(app.AnnotatedCalls,app.CallsDetected);
                    tdata = table(TP',FP',FN','VariableNames',{'TP','FP','FN'});
                    app.UITable.RowName = {app.micNames(1),app.micNames(2),app.micNames(3),app.micNames(4)};
                    app.UITable.Data = tdata;
                end
                
                for rec = 1:size(app.AudioFilePlay,2)
                    audioplay = [];
                    if app.subInterval(2) > size(app.AudioFilePlay,1) 
                        audioplay = app.AudioFilePlay(app.subInterval(1):size(app.AudioFilePlay,1),rec);
                    else
                        audioplay = app.AudioFilePlay(app.subInterval(1):app.subInterval(2),rec);
                    end
                    if ~isempty(audioplay)
                        app.Recordings{rec} = audioplayer(audioplay,app.Fs);
                    end
                end
            
            end
        end
    end
    
    methods (Access = private)
        function map(app)
            gx = geoaxes('parent',app.Panel);
            
            GT = [];
            fileloc = fullfile(app.dataPath,"GT","GT.xlsx");
            if exist(fileloc) == 2
                data = readtable(fileloc);
                micNames = data.Properties.VariableNames;
                GTtemp = data{:,:};
                totalseconds = app.curLoadInterval*app.loadIntervalRate + app.curSubInterval*app.loadSubIntervalRate;
                secIndex = find(GTtemp(:,1) - totalseconds <= 0);
                GT = GTtemp(secIndex(end),:);
            end
            
            if ~isempty(app.micpos) && ~isempty(app.localizations)
                if isempty(GT)
                    geoplot(gx,app.micpos(:,1),app.micpos(:,2),'.y',...
                           app.localizations(:,3),app.localizations(:,4),'.r','MarkerSize',20);
                    
                    legend(gx,["Microphones","Localizations"]);
                else
                    geoplot(gx,app.micpos(:,1),app.micpos(:,2),'.y',...
                            GT(:,2),GT(:,3),'.c',...
                           app.localizations(:,3),app.localizations(:,4),'.r','MarkerSize',20);
                    legend(gx,["Microphones","Speaker","Localizations"]);
                    gx.Children(1,1).ButtonDownFcn = @app.displayDistance;
                    app.speakerLoc = ll2utm(GT(:,2),GT(:,3));
                end
                gx.Children(1,1).ZData = app.localizations(:,2);
                
            elseif ~isempty(app.micpos) && isempty(app.localizations)
                if isempty(GT)
                    geoplot(gx,app.micpos(:,1),app.micpos(:,2),'.y','MarkerSize',20);
                    legend(gx,["Microphones"]);
                else
                    geoplot(gx,app.micpos(:,1),app.micpos(:,2),'.y',...
                            GT(:,2),GT(:,3),'.c', 'MarkerSize',20);
                    legend(gx,["Microphones","Speaker"]);
                end
            end
            geobasemap(gx, 'satellite');
            
        end
        
        function displayDistance(app,~,evt,~,~)
            loc = evt.IntersectionPoint(1,1:2);
            distance = norm(ll2utm(loc(:,1),loc(:,2))-app.speakerLoc);
            app.DistanceLabel.Text = "Distance between selected and speaker: "+num2str(distance)+"m";
        end
        
        function detectMapCalls(app)
            [CallA,CallB,CallC,CallD,app.CallsDetected] = QCallDetection(app);
            if strcmp(app.MapSwitch.Value,'On')
                app.localizations = [];
                if app.FindButton.Value == 0
                    if (~isempty(CallA) + ~isempty(CallB) + ~isempty(CallC) + ~isempty(CallD))/4  >= 3/4
                        app.matchedMatrix = GM_MatchCalls(CallA,CallB,CallC,CallD,GM_EstimateMaxTimeLag(readtable(app.metPaths(1)),...
                            readtable(app.metPaths(2)),readtable(app.metPaths(3)),readtable(app.metPaths(4))));
                        LinesMatchedCalls(app);
                    end
                end
                if ~isempty(app.matchedMatrix)
                    app.localizations = Localization(app,app.matchedMatrix);
                end
                LocalizedCalls(app);
                
                app.map()
            end
        end
        
        function Y = LinesMatchedCalls(app)
            colors = ["#FF00FF","#FFA500","#FFFFFF"];
            numOfMatched = size(app.matchedMatrix,2);
            if strcmp(app.ModeSwitch.Value,"Online")
                % Draw lines on calls
                for column = 1:numOfMatched
                    line(app.UIAxes,app.matchedMatrix(1,column)*ones(1,length(app.F)),app.F,'Color',colors(mod(column,3)+1),'LineWidth',1.5);
                    line(app.UIAxes_2,app.matchedMatrix(2,column)*ones(1,length(app.F)),app.F,'Color',colors(mod(column,3)+1),'LineWidth',1.5);
                    line(app.UIAxes_3,app.matchedMatrix(3,column)*ones(1,length(app.F)),app.F,'Color',colors(mod(column,3)+1),'LineWidth',1.5);
                    line(app.UIAxes_4,app.matchedMatrix(4,column)*ones(1,length(app.F)),app.F,'Color',colors(mod(column,3)+1),'LineWidth',1.5);
                end
            end
            
        end
        
        function Y = LocalizedCalls(app)
            colors = ["#FF00FF","#FFA500","#FFFFFF"];
            for i = 1:size(app.localizations,1)
                text(app.UIAxes,app.matchedMatrix(1,app.localizations(i,1))+0.15,...
                    app.F(end)-200, num2str(i),'Color', colors(mod(app.localizations(i,1),3)+1),"FontSize",14);
                text(app.UIAxes_2,app.matchedMatrix(2,app.localizations(i,1))+0.15,...
                    app.F(end)-200, num2str(i),'Color', colors(mod(app.localizations(i,1),3)+1),"FontSize",14);
                text(app.UIAxes_3,app.matchedMatrix(3,app.localizations(i,1))+0.15,...
                    app.F(end)-200, num2str(i),'Color', colors(mod(app.localizations(i,1),3)+1),"FontSize",14);
                text(app.UIAxes_4,app.matchedMatrix(4,app.localizations(i,1))+0.15,...
                    app.F(end)-200, num2str(i),'Color', colors(mod(app.localizations(i,1),3)+1),"FontSize",14);
            end
        end
        
        function Y = ReDrawSpecs(app)
            %Spectrograms
            curtime = app.curLoadInterval*app.loadIntervalRate + app.curSubInterval*app.loadSubIntervalRate;
            time = curtime:1:(curtime+10);
            timestr = app.convertToRealTime(time);
            
            imagesc(app.UIAxes,time,app.F,app.Spectrograms{1})
            imagesc(app.UIAxes_2,time,app.F,app.Spectrograms{2})
            imagesc(app.UIAxes_3,time,app.F,app.Spectrograms{3})
            imagesc(app.UIAxes_4,time,app.F,app.Spectrograms{4})
            
            app.UIAxes.YDir = 'normal'; app.UIAxes.XLim = [time(1),time(end)];
            app.UIAxes_2.YDir = 'normal'; app.UIAxes_2.XLim = [time(1),time(end)];
            app.UIAxes_3.YDir = 'normal'; app.UIAxes_3.XLim = [time(1),time(end)];
            app.UIAxes_4.YDir = 'normal'; app.UIAxes_4.XLim = [time(1),time(end)];
            app.UIAxes.YLim = [app.F(1),app.F(end)]; app.UIAxes.XTickLabel = timestr;
            app.UIAxes_2.YLim = [app.F(1),app.F(end)]; app.UIAxes_2.XTickLabel = timestr;
            app.UIAxes_3.YLim = [app.F(1),app.F(end)]; app.UIAxes_3.XTickLabel = timestr;
            app.UIAxes_4.YLim = [app.F(1),app.F(end)]; app.UIAxes_4.XTickLabel = timestr;
        end
        
        function Y = ReDrawLines(app)
            % Draw lines on calls
            if app.OffButton.Value == 1 && strcmp(app.ModeSwitch.Value,"Online")
                for row = 1:size(app.CallsDetected{1},1)
                    line(app.UIAxes,app.CallsDetected{1}(row,:)*ones(1,length(app.F)),app.F,'Color','red','LineWidth',1.5);
                end
                for row = 1:size(app.CallsDetected{2},1)
                    line(app.UIAxes_2,app.CallsDetected{2}(row,:)*ones(1,length(app.F)),app.F,'Color','red','LineWidth',1.5);
                end
                for row = 1:size(app.CallsDetected{3},1)
                    line(app.UIAxes_3,app.CallsDetected{3}(row,:)*ones(1,length(app.F)),app.F,'Color','red','LineWidth',1.5);
                end
                for row = 1:size(app.CallsDetected{4},1)
                    line(app.UIAxes_4,app.CallsDetected{4}(row,:)*ones(1,length(app.F)),app.F,'Color','red','LineWidth',1.5);
                end
            end
        end
        
        function Y = ReDrawAnnotations(app)
            %% Drawing Bounding Boxes for annotated detections
            if app.OffButton_2.Value == 1 && strcmp(app.ModeSwitch.Value,"Online")
                if app.OffButton_2.Value == 1 && strcmp(app.ModeSwitch.Value,"Online")
                    if ~isempty(app.AnnotatedPos{1})
                        for row = 1:size(app.AnnotatedPos{1},1)
                            rectangle(app.UIAxes,'Position',app.AnnotatedPos{1}(row,:),'EdgeColor','green','LineWidth',1.5)
                        end
                    end
                    if ~isempty(app.AnnotatedPos{2})
                        for row = 1:size(app.AnnotatedPos{2},1)
                            rectangle(app.UIAxes_2,'Position',app.AnnotatedPos{2}(row,:),'EdgeColor','green','LineWidth',1.5)
                        end
                    end
                    if ~isempty(app.AnnotatedPos{3})
                        for row = 1:size(app.AnnotatedPos{3},1)
                            rectangle(app.UIAxes_3,'Position',app.AnnotatedPos{3}(row,:),'EdgeColor','green','LineWidth',1.5)
                        end
                    end
                    if ~isempty(app.AnnotatedPos{4})
                        for row = 1:size(app.AnnotatedPos{4},1)
                            rectangle(app.UIAxes_4,'Position',app.AnnotatedPos{4}(row,:),'EdgeColor','green','LineWidth',1.5)
                        end
                    end
                end
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Size changed function: SpectrogramsPanel
        function SpectrogramsPanelSizeChanged(app, event)
            position = app.SpectrogramsPanel.Position;
        end

        % Value changed function: MapSwitch
        function MapSwitchValueChanged(app, event)
            if strcmp(app.ModeSwitch.Value,"Online")
                if strcmp(app.MapSwitch.Value,'On')
                    app.detectMapCalls();
                    app.FindButton.Text = 'Displaying';
                    app.FindButton.Value = 0;
                else
                    delete(app.Panel.Children)
                    ReDrawSpecs(app);
                    ReDrawLines(app);
                    ReDrawAnnotations(app);
                    app.FindButton.Text = 'Find';
                end
            end
        end

        % Value changed function: ModeSwitch
        function ModeSwitchValueChanged(app, event)
            if strcmp(app.ModeSwitch.Value,"Online") && app.dataLoaded
                if ~isempty(app.micPaths)
                    for i = 1:4     % 4 = length(app.micPaths)
                        name = split(app.micPaths(i).name,'_');
                        app.micNames(i) = name{1};
                    end
                    app.UIAxes.Title.String = app.micNames(1);
                    app.UIAxes_2.Title.String = app.micNames(2);
                    app.UIAxes_3.Title.String = app.micNames(3);
                    app.UIAxes_4.Title.String = app.micNames(4);
                end
                
                [app.Spectrograms, app.S_Channels]  = FBspectrogram(app);
                
            else
                if app.dataLoaded % fix 6/17/2020
                    delete(app.Panel.Children)
                    for i = 5:8
                        cla(app.SpectrogramsPanel.Children.Children(i))
                    end
                    app.MapSwitch.Value = 'Off';
                    app.OffButton.Value = 0;
                    app.OffButton.Text = 'Off';
                    app.OffButton_2.Value = 0;
                    app.OffButton_2.Text = 'Off';
                    
                    app.Panel_3.Visible = 1;
                    
                end
            end
        end

        % Menu selected function: ImportMenu
        function ImportMenuSelected(app, event)
            app.dataPath = uigetdir('','Select Recordings Folder'); %Prompt user for a directory
            if exist(fullfile(app.dataPath,"Mics")) == 7 %Check if Mics is a subdirectory
                app.micPaths = []; 
                app.NoRecordingsLoadedYetLamp.Color = 'Yellow';
                app.NorecordingsloadedyetLabel.Text =  'Recordings Being Processed!'
                drawnow; 
                
                %Load in recordings
                app = HTdataAccess(app,'read');
                if ~isnan(app.AudioFilePlay)
                    app.dataLoaded = true;
                end
                
                if ~isempty(app.micPaths)
                    for i = 1:4     % 4 = length(app.micPaths)
                        name = split(app.micPaths(i).name,'_');
                        app.micNames(i) = name{1};
                    end
                    app.UIAxes.Title.String = app.micNames(1);
                    app.UIAxes_2.Title.String = app.micNames(2);
                    app.UIAxes_3.Title.String = app.micNames(3);
                    app.UIAxes_4.Title.String = app.micNames(4);
                end
                
                [app.Spectrograms, app.S_Channels] = FBspectrogram(app);
                app.NoRecordingsLoadedYetLamp.Color = 'Green';
                app.NorecordingsloadedyetLabel.Text =  'Recordings Loaded!'
            end
        end

        % Value changed function: SiftButton
        function SiftButtonValueChanged(app, event)
            app.SiftButton.Value = 0;
            if strcmp(app.ModeSwitch.Value,"Online")
                %Take user input
                refTime = split(string(app.Date),' ');
                refTime = str2num(char(split(refTime(2),':')));
                siftTime = str2num(char(split(app.EditField.Value, ':')));
                
                %Convert time values into seconds into the recording
                difTime = siftTime - refTime;                
                totalSeconds = difTime(1)*3600 + difTime(2)*60 + difTime(3);
                
                %Update the location in the recording and loading in the
                %corresponding spectrogram.
                app = UpdateAudio(app,totalSeconds);
                %handles=HTcompute(handles);
                
                if strcmp(app.MapSwitch.Value,'On')
                    delete(app.Panel.Children)
                    app.detectMapCalls();
                    app.map()
                end
                
            end
        end

        % Value changed function: Button_5
        function Button_5ValueChanged(app, event)
            app.Button_5.Value =0;
            if strcmp(app.ModeSwitch.Value,"Online")
                refTime = split(string(app.Date),' ');
                refTime = str2num(char(split(refTime(2),':')));
                
                %Go back 10 seconds of the reference time value
                totalSeconds = (app.curLoadInterval*app.loadIntervalRate + app.curSubInterval*app.loadSubIntervalRate) - 10;
                app = UpdateAudio(app,totalSeconds);
                
                if strcmp(app.MapSwitch.Value,'On')
                    delete(app.Panel.Children)
                    app.detectMapCalls();
                    app.map()
                end
                
            end
        end

        % Value changed function: Button_6
        function Button_6ValueChanged(app, event)
            app.Button_6.Value =0;
            if strcmp(app.ModeSwitch.Value,"Online")
                refTime = split(string(app.Date),' ');
                refTime = str2num(char(split(refTime(2),':')));
                totalSeconds = (app.curLoadInterval*app.loadIntervalRate + app.curSubInterval*app.loadSubIntervalRate) + 10;
                app = UpdateAudio(app,totalSeconds);
                
                if strcmp(app.MapSwitch.Value,'On')
                    delete(app.Panel.Children)
                    app.detectMapCalls();
                    app.map()
                end
                
            end
        end

        % Value changed function: OffButton
        function QuailCallDetectionButtonValueChanged(app, event)
            if strcmp(app.ModeSwitch.Value,"Online")
                if ~isempty(app.Spectrograms{1})
                    if app.OffButton.Value == 1
                        [~,~,~,~,app.CallsDetected] = QCallDetection(app);
                        app.OffButton.Text = 'On';
                        
                        if strcmp(app.MapSwitch.Value,"On")
                            LinesMatchedCalls(app);
                            LocalizedCalls(app);
                        end
                        
                        if app.FindButton.Value == 1
                            LinesMatchedCalls(app); 
                        end
                        
                        if app.OffButton_2.Value == 1
                            [TP,FP,FN] = confusionMat(app.AnnotatedCalls,app.CallsDetected);
                            tdata = table(TP',FP',FN','VariableNames',{'TP','FP','FN'});
                            app.UITable.RowName = {app.micNames(1),app.micNames(2),app.micNames(3),app.micNames(4)};
                            app.UITable.Data = tdata;
                        end
                        
                    else
                        app.OffButton.Text = 'Off';
                        
                        ReDrawSpecs(app); %Spectrograms
                        
                        % Drawing Bounding Boxes for annotated detections
                        ReDrawAnnotations(app);
                        
                        if strcmp(app.MapSwitch.Value,"On")
                            LinesMatchedCalls(app);
                            LocalizedCalls(app);
                        end
                        
                        if app.FindButton.Value == 1
                            LinesMatchedCalls(app); 
                        end
                        
                    end
                else
                    app.OffButton.Value = 0;
                    app.OffButton.Text = 'Off';
                end
            else
                app.OffButton.Value = 0;
                app.OffButton.Text = 'Off';
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            curtime = app.curLoadInterval*app.loadIntervalRate + app.curSubInterval*app.loadSubIntervalRate;
            time = curtime:1:(curtime+10);
            Bar = line(app.UIAxes,time(1)*ones(1,length(app.F)),app.F,'Color','yellow','LineWidth',1.5);
            play(app.Recordings{1});
            for i = 1:100
                set(Bar,'XData',Bar.XData+0.1);
                pause(.1);
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            curtime = app.curLoadInterval*app.loadIntervalRate + app.curSubInterval*app.loadSubIntervalRate;
            time = curtime:1:(curtime+10);
            Bar = line(app.UIAxes_2,time(1)*ones(1,length(app.F)),app.F,'Color','yellow','LineWidth',1.5);
            play(app.Recordings{2});
            for i = 1:100
                set(Bar,'XData',Bar.XData+0.1);
                pause(.1);
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            curtime = app.curLoadInterval*app.loadIntervalRate + app.curSubInterval*app.loadSubIntervalRate;
            time = curtime:1:(curtime+10);
            Bar = line(app.UIAxes_3,time(1)*ones(1,length(app.F)),app.F,'Color','yellow','LineWidth',1.5);
            play(app.Recordings{3});
            for i = 1:100
                set(Bar,'XData',Bar.XData+0.1);
                pause(.1);
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            curtime = app.curLoadInterval*app.loadIntervalRate + app.curSubInterval*app.loadSubIntervalRate;
            time = curtime:1:(curtime+10);
            Bar = line(app.UIAxes_4,time(1)*ones(1,length(app.F)),app.F,'Color','yellow','LineWidth',1.5);
            play(app.Recordings{4});
            for i = 1:100
                set(Bar,'XData',Bar.XData+0.1);
                pause(.1);
            end
        end

        % Value changed function: OffButton_2
        function OffButton_2ValueChanged(app, event)
            if strcmp(app.ModeSwitch.Value,"Online")
                if ~isempty(app.Spectrograms{1})
                    if app.OffButton_2.Value == 1
                        [app.AnnotatedCalls,app.AnnotatedPos] = annotationsBBox(app);
                        app.OffButton_2.Text = 'On';
                        
                        if app.OffButton.Value == 1
                            [TP,FP,FN] = confusionMat(app.AnnotatedCalls,app.CallsDetected);
                            tdata = table(TP',FP',FN','VariableNames',{'TP','FP','FN'});
                            app.UITable.RowName = {app.micNames(1),app.micNames(2),app.micNames(3),app.micNames(4)};
                            app.UITable.Data = tdata;
                        end
                        
                    else
                        app.OffButton_2.Text = 'Off';   
                        
                        %Spectrograms
                        ReDrawSpecs(app);
                        
                        %% Drawing Lines on Calls
                        ReDrawLines(app);
                        
                        if strcmp(app.MapSwitch.Value,"On")
                            LinesMatchedCalls(app);
                            LocalizedCalls(app);
                        end
                        
                        if app.FindButton.Value == 1
                            LinesMatchedCalls(app); 
                        end
                       
                    end
                else
                    app.OffButton_2.Value = 0;
                    app.OffButton_2.Text = 'Off';
                end
            else
                app.OffButton_2.Value = 0;
                app.OffButton_2.Text = 'Off';
            end
        end

        % Menu selected function: LocalizeMenu
        function LocalizeMenuSelected(app, event)
            app.dataPath = uigetdir('','Select Recordings Folder'); %Prompt user for a directory
            if exist(fullfile(app.dataPath,"Mics")) == 7 && exist(fullfile(app.dataPath,"misc")) == 7 && exist(fullfile(app.dataPath,"misc","localize.xlsx")) == 2
                fileloc = fullfile(app.dataPath,"misc","localize.xlsx");
                resultfile = fullfile(app.dataPath,"results.xlsx");
                data = readtable(fileloc);
                micNames = data.Properties.VariableNames;
                matchedMatrix = data{:,:}';
                
                mics = dir(fullfile(app.dataPath,"Mics"));
                micPaths = "";
                for i = 1:length(mics)
                    filenamespl = split(convertCharsToStrings(mics(i).name), "_");
                    micName = filenamespl(1);
                    ext = filenamespl(end);
                    if strcmp(lower(ext),'summary.txt')
                       micPaths(find(strcmp(micName,micNames))) = string(mics(i).name);
                    end
                end
                app.micPaths = micPaths;
                
                app.micpos = [];
                for k=1:size(app.micPaths,2)
                    metfilename = fullfile(app.dataPath,'Mics',app.micPaths(k));
                    micData = readtable(metfilename);
                    temp = [];
                    if char(micData{1,4}) == 'N'
                        temp(1) = mean(micData{:,3});
                    else
                        temp(1) = - mean(micData{:,3});
                    end
                    if char(micData{1,6}) == 'E'
                        temp(2) =  mean(micData{:,5});
                    else
                        temp(2) = - mean(micData{:,5});
                    end
                    temp(3) = 0;
                    temp(4) =  mean(micData{:,9});
                    app.micpos = [app.micpos; temp];
                end
                
                locs = Localization(app,matchedMatrix);
                
                
                T = table((1:size(matchedMatrix,2))',matchedMatrix(1,:)',matchedMatrix(2,:)',matchedMatrix(3,:)',matchedMatrix(4,:)');
                T.Properties.VariableNames = ["Number Matched Call" micNames];
                writetable(T,resultfile,"Sheet","matchedMatrix");
                
                T = table(locs(:,1),locs(:,2),locs(:,3));
                T.Properties.VariableNames = ["Number Matched Call","Latitude", "Longitude"];
                writetable(T,resultfile,"Sheet","Localizations");
                
                % Clear
                clear app.dataPath;clear app.micPaths;clear app.micpos;
            end
        end

        % Value changed function: QuailCallTemplateDropDown
        function QuailCallTemplateDropDownValueChanged(app, event)
            value = app.QuailCallTemplateDropDown.Value;
            if strcmp(value,"Import")
                app.template = uigetfile('*.jpg');
                app.template = mat2gray(double(imread(app.template)));
                if app.OffButton.Value == 1
                    %Spectrograms
                    ReDrawSpecs(app);
                    
                    %Qcalls
                    [~,~,~,~,app.CallsDetected] = QCallDetection(app);
                end
                
            else
                if strcmp(value,"Synthetic")
                    app.template = mat2gray(double(imread('template_synthetic.jpg')));
                end
                
                if app.OffButton.Value == 1
                    %Spectrograms
                    ReDrawSpecs(app);
                    
                    %Qcalls
                    [~,~,~,~,app.CallsDetected] = QCallDetection(app);
                end
            end
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            % Get localizations
            filename = fullfile(pwd,"S2 Sound Finder for Spreadsheets.xls");
            app.localizations = readmatrix(filename,"Sheet","results (batch)","Range","A2:C"+num2str(2+size(app.localizations,1)));%Fix here.
            locsGPS = app.localizations;
            east = locsGPS(~isnan(locsGPS(:,2)),2);
            north = locsGPS(~isnan(locsGPS(:,2)),3);
            locsGPS(~isnan(locsGPS(:,2)),3:4) = utm2ll(east,north,14*ones(size(east,1),1)); 
            
            %Write data into results file
            resultfile = fullfile(app.dataPath,"results.xlsx");
            T = table(app.localizations(:,1),app.localizations(:,2),app.localizations(:,3));
            T.Properties.VariableNames = ["Number Matched Call","Easting", "Northing"];
            writetable(T,resultfile,"Sheet","Localizations(UTM)");
            
            resultfile = fullfile(app.dataPath,"results.xlsx");
            T = table(locsGPS(:,1),locsGPS(:,3),locsGPS(:,4));
            T.Properties.VariableNames = ["Number Matched Call","Latitude", "Longitude"];
            writetable(T,resultfile,"Sheet","Localizations(GPS)");
            
            %Change button settings back.
            app.FinishButton.BackgroundColor = [0.65,0.65,0.65];
            app.BatchprocessLabel.Text = "";
            app.FinishButton.Visible = false;
            
            %Map Results
            app.localizations = locsGPS;
            app.map();
            app.MapSwitch.Value = "On";
        end

        % Value changed function: FindButton
        function FindButtonValueChanged(app, event)
            if strcmp(app.ModeSwitch.Value,"Online") && strcmp(app.MapSwitch.Value,'Off')
                if app.FindButton.Value == 1
                    app.FindButton.Text = 'Displaying';
                    [CallA,CallB,CallC,CallD,app.CallsDetected] = QCallDetection(app);
                    if (~isempty(CallA) + ~isempty(CallB) + ~isempty(CallC) + ~isempty(CallD))/4  >= 3/4
                        app.matchedMatrix = GM_MatchCalls(CallA,CallB,CallC,CallD,GM_EstimateMaxTimeLag(readtable(app.metPaths(1)),...
                            readtable(app.metPaths(2)),readtable(app.metPaths(3)),readtable(app.metPaths(4))));
                        LinesMatchedCalls(app);
                    end
                else
                    app.FindButton.Text = 'Find';
                    
                    %Spectrograms
                    ReDrawSpecs(app);
                    
                    %% Drawing Lines on Calls
                    ReDrawLines(app);
                    
                    %Drawing annotation boxes
                    ReDrawAnnotations(app);
                end
            else
                app.FindButton.Value = 0;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.Panel_3.Visible = 0;
            
            updateLoadInterval = false;
            if strcmp(app.BatchProcessingTypeSwitch.Value, "Parallel")
                [~,sys] = memory;
                x = round((sys.PhysicalMemory.Total - 5e9)/app.parallelRate);
                if x < 60
                    app.BatchProcessingTypeSwitch.Value = "Sequential";
                else
                    app.loadIntervalRate = x;
                    updateLoadInterval = true;
                end
            end
            
            app.NoRecordingsLoadedYetLamp.Color = "Yellow";
            app.NorecordingsloadedyetLabel.Text = "Batch Processing(0/100%)";
            drawnow;
            batchProcess(app,updateLoadInterval);
            if strcmp(app.ModeSwitch.Value,"Offline")
                app.NoRecordingsLoadedYetLamp.Color = "Green";
                app.NorecordingsloadedyetLabel.Text = "Batch Processing(100/100%)";
            else
                app.NoRecordingsLoadedYetLamp.Color = "Green";
                app.NorecordingsloadedyetLabel.Text = "Online Mode Ready!";
            end
        end

        % Value changed function: DisplayModeDropDown
        function DisplayModeDropDownValueChanged(app, event)
            %For Farshad
            if strcmp(app.DisplayModeDropDown.Value, "Spectrogram")
                app.displayMode = 0;
            else
                app.displayMode = 1;
            end
            app.ChannelDropDown.Visible = ~app.displayMode;
            app.ChannelDropDownLabel.Visible = ~app.displayMode;
            %app = UpdateAudio();
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            if ~strcmp(app.channel,app.ChannelDropDown.Value)
                app.channel = app.ChannelDropDown.Value;
                
                %Update Spectrograms
                totalSeconds = (app.curLoadInterval*app.loadIntervalRate + app.curSubInterval*app.loadSubIntervalRate);
                app.UpdateAudio(totalSeconds);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QuailkitUIFigure = uifigure('Visible', 'off');
            app.QuailkitUIFigure.Name = 'Quailkit';
            app.FileMenu = uimenu(app.QuailkitUIFigure);
            app.FileMenu.Text = 'File';
            app.ImportMenu = uimenu(app.FileMenu);
            app.ImportMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportMenuSelected, true);
            app.ImportMenu.Text = 'Import';
            app.LocalizeMenu = uimenu(app.FileMenu);
            app.LocalizeMenu.MenuSelectedFcn = createCallbackFcn(app, @LocalizeMenuSelected, true);
            app.LocalizeMenu.Text = 'Localize';
            app.SettingsMenu = uimenu(app.FileMenu);
            app.SettingsMenu.Text = 'Settings';
            app.GridLayout2 = uigridlayout(app.QuailkitUIFigure);
            app.SpectrogramsPanel = uipanel(app.GridLayout2);
            app.SpectrogramsPanel.Title = 'Spectrograms';
            app.SpectrogramsPanel.SizeChangedFcn = createCallbackFcn(app, @SpectrogramsPanelSizeChanged, true);
            app.GridLayout = uigridlayout(app.SpectrogramsPanel);
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'play.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'play.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'play.png';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'play.png';
            app.Button_4.Text = '';
            app.UIAxes_4 = uiaxes(app.GridLayout);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'Time')
            ylabel(app.UIAxes_4, 'Frequency')
            app.UIAxes_3 = uiaxes(app.GridLayout);
            title(app.UIAxes_3, 'Title')
            ylabel(app.UIAxes_3, 'Frequency')
            app.UIAxes_2 = uiaxes(app.GridLayout);
            title(app.UIAxes_2, 'Title')
            ylabel(app.UIAxes_2, 'Frequency')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            ylabel(app.UIAxes, 'Frequency')
            app.UIAxes.XLim = [-0.205712819898421 1.00428718010158];
            app.UIAxes.YLim = [-0.0834924502891797 1.12650754971082];
            app.UIAxes.ZLim = [0 1];
            app.Panel = uipanel(app.GridLayout2);
            app.ModeSwitchLabel = uilabel(app.GridLayout2);
            app.ModeSwitchLabel.Text = 'Mode';
            app.ModeSwitch = uiswitch(app.GridLayout2, 'slider');
            app.ModeSwitch.Items = {'Offline', 'Online'};
            app.ModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ModeSwitchValueChanged, true);
            app.ModeSwitch.Value = 'Online';
            app.MapSwitchLabel = uilabel(app.GridLayout2);
            app.MapSwitchLabel.Text = 'Map';
            app.MapSwitch = uiswitch(app.GridLayout2, 'slider');
            app.MapSwitch.ValueChangedFcn = createCallbackFcn(app, @MapSwitchValueChanged, true);
            app.Panel_2 = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.SiftButton = uibutton(app.GridLayout3, 'state');
            app.SiftButton.ValueChangedFcn = createCallbackFcn(app, @SiftButtonValueChanged, true);
            app.SiftButton.Text = 'Sift';
            app.Button_5 = uibutton(app.GridLayout3, 'state');
            app.Button_5.ValueChangedFcn = createCallbackFcn(app, @Button_5ValueChanged, true);
            app.Button_5.Icon = 'backwards.png';
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.GridLayout3, 'state');
            app.Button_6.ValueChangedFcn = createCallbackFcn(app, @Button_6ValueChanged, true);
            app.Button_6.Icon = 'forward.png';
            app.Button_6.Text = '';
            app.EditField = uieditfield(app.GridLayout3, 'text');
            app.EditField.Value = '00:00:00';
            app.OffButton = uibutton(app.GridLayout3, 'state');
            app.OffButton.ValueChangedFcn = createCallbackFcn(app, @QuailCallDetectionButtonValueChanged, true);
            app.OffButton.Text = 'Off';
            app.QuailCallDetectionLabel = uilabel(app.GridLayout3);
            app.QuailCallDetectionLabel.Text = 'Quail Call Detection';
            app.Previous10sLabel = uilabel(app.GridLayout3);
            app.Previous10sLabel.Text = 'Previous 10s';
            app.Next10sLabel = uilabel(app.GridLayout3);
            app.Next10sLabel.Text = 'Next 10s';
            app.ManualAnnotationsLabel = uilabel(app.GridLayout3);
            app.ManualAnnotationsLabel.Text = 'Manual Annotations';
            app.OffButton_2 = uibutton(app.GridLayout3, 'state');
            app.OffButton_2.ValueChangedFcn = createCallbackFcn(app, @OffButton_2ValueChanged, true);
            app.OffButton_2.Text = 'Off';
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {'TP'; 'FP'; 'FN'};
            app.UITable.RowName = {''};
            app.NoRecordingsLoadedYetLamp = uilamp(app.GridLayout2);
            app.NorecordingsloadedyetLabel = uilabel(app.GridLayout2);
            app.NorecordingsloadedyetLabel.Text = 'No recordings loaded yet';
            app.QuailCallTemplateDropDownLabel = uilabel(app.GridLayout2);
            app.QuailCallTemplateDropDownLabel.Text = 'Quail Call Template';
            app.QuailCallTemplateDropDown = uidropdown(app.GridLayout2);
            app.QuailCallTemplateDropDown.Items = {'Real Bird', 'Synthetic', 'Import'};
            app.QuailCallTemplateDropDown.ValueChangedFcn = createCallbackFcn(app, @QuailCallTemplateDropDownValueChanged, true);
            app.QuailCallTemplateDropDown.Value = 'Real Bird';
            app.FinishButton = uibutton(app.GridLayout2, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Visible = 'off';
            app.FinishButton.Text = 'Finish';
            app.BatchprocessLabel = uilabel(app.GridLayout2);
            app.BatchprocessLabel.Text = '';
            app.MatchedCallsLabel = uilabel(app.GridLayout2);
            app.MatchedCallsLabel.Text = 'Matched Calls';
            app.FindButton = uibutton(app.GridLayout2, 'state');
            app.FindButton.ValueChangedFcn = createCallbackFcn(app, @FindButtonValueChanged, true);
            app.FindButton.Text = 'Find';
            app.DistanceLabel = uilabel(app.GridLayout2);
            app.DistanceLabel.Text = '';
            app.Panel_3 = uipanel(app.GridLayout2);
            app.Panel_3.Title = 'Panel';
            app.Panel_3.Visible = 'off';
            app.BatchProcessingTypeSwitchLabel = uilabel(app.Panel_3);
            app.BatchProcessingTypeSwitchLabel.Text = 'Batch Processing Type';
            app.BatchProcessingTypeSwitch = uiswitch(app.Panel_3, 'slider');
            app.BatchProcessingTypeSwitch.Items = {'Sequential', 'Parallel'};
            app.BatchProcessingTypeSwitch.Value = 'Sequential';
            app.StartButton = uibutton(app.Panel_3, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.DisplayModeDropDownLabel = uilabel(app.GridLayout2);
            app.DisplayModeDropDownLabel.Text = 'Display Mode';
            app.DisplayModeDropDown = uidropdown(app.GridLayout2);
            app.DisplayModeDropDown.Items = {'Spectrogram', 'Scalogram'};
            app.DisplayModeDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayModeDropDownValueChanged, true);
            app.DisplayModeDropDown.Value = 'Spectrogram';
            app.ChannelDropDownLabel = uilabel(app.GridLayout2);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.GridLayout2);
            app.ChannelDropDown.Items = {'Both', 'Channel 1', 'Channel 2'};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = 'Both';
            % Show the figure after all components are created
            app.QuailkitUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QuailKit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QuailkitUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QuailkitUIFigure)
        end
    end
end